classdef Entrenamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        NmeromximodepocasEditField  matlab.ui.control.NumericEditField
        NmeromximodepocasEditFieldLabel  matlab.ui.control.Label
        TasadeaprendizajeinicialEditField  matlab.ui.control.NumericEditField
        TasadeaprendizajeinicialEditFieldLabel  matlab.ui.control.Label
        SolverButtonGroup           matlab.ui.container.ButtonGroup
        rmspropButton               matlab.ui.control.RadioButton
        adamButton                  matlab.ui.control.RadioButton
        sgdmButton                  matlab.ui.control.RadioButton
        SaveButton                  matlab.ui.control.Button
        HomePageButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HomePageButton
        function HomePagePushed(app, event)
             % Se vuelve a la página principal
             Principal;
             
             % Se cierra la ventana del entrenamiento
             delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            % Sequence-to-Sequence Classification Using Deep Learning
            % This example shows how to classify each time step of sequence data using a long short-term memory (LSTM) network.
            % To train a deep neural network to classify each time step of sequence data, you can use a sequence-to-sequence LSTM network. A sequence-to-sequence LSTM network enables you to make different predictions for each individual time step of the sequence data.
            % This example uses sensor data obtained from a smartphone worn on the body. The example trains an LSTM network to recognize the activity of the wearer given time series data representing accelerometer readings in three different directions. The training data contains time series data for seven people. Each sequence has three features and varies in length. The data set contains six training observations and one test observation.
            % Load Sequence Data
            % Load the human activity recognition data. The data contains seven time series of sensor data obtained from a smartphone worn on the body. Each sequence has three features and varies in length. The three features correspond to the accelerometer readings in three different directions.

            load HumanActivityTrain
            XTrain

            % Visualize one training sequence in a plot. Plot the first feature of the first training sequence and color the plot according to the corresponding activity.
            X = XTrain{1}(1,:);
            classes = categories(YTrain{1});

            figure
            for j = 1:numel(classes)
                label = classes(j);
                idx = find(YTrain{1} == label);
                hold on
                plot(idx,X(idx))
            end
            hold off

            xlabel("Paso Tiempo")
            ylabel("Acceleración")
            title("Entrenamiento secuencia 1, Característica 1")
            legend(classes,'Location','northwest')

            % Define LSTM Network Architecture
            % Define the LSTM network architecture. Specify the input to be sequences of size 3 (the number of features of the input data). Specify an LSTM layer with 200 hidden units, and output the full sequence. Finally, specify five classes by including a fully connected layer of size 5, followed by a softmax layer and a classification layer.
            numFeatures = 3;
            numHiddenUnits = 200;
            numClasses = 5;

            layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(numHiddenUnits,'OutputMode','sequence')
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
            
            % Specify the training options. Set the solver to 'adam'. Train for 60 epochs. To prevent the gradients from exploding, set the gradient threshold to 2.
            % Creo las variables y obtengo su valor
            solverName = app.SolverButtonGroup.SelectedObject.Text;
            InitialLearnRate = app.TasadeaprendizajeinicialEditField.Value;
            MaxEpochs = app.NmeromximodepocasEditField.Value;

            options = trainingOptions(solverName, ...
                'MaxEpochs',MaxEpochs, ...
                'GradientThreshold',2, ...
                'Verbose',0, ...
                'InitialLearnRate', InitialLearnRate, ...
                'Plots','training-progress');

            % Train the LSTM network with the specified training options using trainNetwork. Each mini-batch contains the whole training set, so the plot is updated once per epoch. The sequences are very long, so it might take some time to process each mini-batch and update the plot.
            net = trainNetwork(XTrain,YTrain,layers,options);
            save RedLSTM net

            % Test LSTM Network
            % Load the test data and classify the activity at each time step.
            % Load the human activity test data. XTest contains a single sequence of dimension 3. YTest is contains sequence of categorical labels corresponding to the activity at each time step.

            load HumanActivityTest
            figure
            plot(XTest{1}')
            xlabel("Paso de tiempo")
            legend("Característica " + (1:numFeatures))
            title("Datos Test")

            %Classify the test data using classify.
            YPred = classify(net,XTest{1});

            % Alternatively, you can make predictions one time step at a time by using classifyAndUpdateState. This is useful when you have the values of the time steps arriving in a stream. Usually, it is faster to make predictions on full sequences when compared to making predictions one time step at a time. For an example showing how to forecast future time steps by updating the network between single time step predictions, see Time Series Forecasting Using Deep Learning.
            % Calculate the accuracy of the predictions.

            acc = sum(YPred == YTest{1})./numel(YTest{1})

            % Compare the predictions with the test data by using a plot.

            figure
            plot(YPred,'.-')
            hold on
            plot(YTest{1})
            hold off

            xlabel("Paso de Tiempo")
            ylabel("Actividad")
            title("Actividades Predichas")
            legend(["Predicción" "Datos Test"])
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HomePageButton = uibutton(app.GridLayout, 'push');
            app.HomePageButton.ButtonPushedFcn = createCallbackFcn(app, @HomePagePushed, true);
            app.HomePageButton.Icon = fullfile(pathToMLAPP, 'icons', 'icon-mat-arrow-back-20.png');
            app.HomePageButton.Text = '';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Comenzar entrenamiento';
            app.SolverButtonGroup = uibuttongroup(app.GridLayout);
            app.SolverButtonGroup.Title = 'Solver';
            app.sgdmButton = uiradiobutton(app.SolverButtonGroup);
            app.sgdmButton.Text = 'sgdm';
            app.sgdmButton.Value = true;
            app.adamButton = uiradiobutton(app.SolverButtonGroup);
            app.adamButton.Text = 'adam';
            app.rmspropButton = uiradiobutton(app.SolverButtonGroup);
            app.rmspropButton.Text = 'rmsprop';
            app.TasadeaprendizajeinicialEditFieldLabel = uilabel(app.GridLayout);
            app.TasadeaprendizajeinicialEditFieldLabel.Text = 'Tasa de aprendizaje inicial';
            app.TasadeaprendizajeinicialEditField = uieditfield(app.GridLayout, 'numeric');
            app.TasadeaprendizajeinicialEditField.Limits = [0 1];
            app.TasadeaprendizajeinicialEditField.Value = 0.01;
            app.NmeromximodepocasEditFieldLabel = uilabel(app.GridLayout);
            app.NmeromximodepocasEditFieldLabel.Text = 'Número máximo de épocas';
            app.NmeromximodepocasEditField = uieditfield(app.GridLayout, 'numeric');
            app.NmeromximodepocasEditField.Limits = [1 Inf];
            app.NmeromximodepocasEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entrenamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end