classdef Principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        HistorialButton      matlab.ui.control.Button
        clasificacion        matlab.ui.control.Label
        ActividadButton      matlab.ui.control.Button
        InicioButton         matlab.ui.control.Button
        EntrenamientoButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            channelID = 1648275;
            readAPIKey = 'YHLBWC3BO8ABNNGB';
            assignin('base','channelID',channelID);
            assignin('base','readAPIKey',readAPIKey);

            addpath('mFiles');
        end

        % Button pushed function: EntrenamientoButton
        function EntrenamientoButtonPushed(app, event)
            
            % Se abre la ventana para introducir los datos del entrenamiento
            Entrenamiento;
            
            % Se cierra la ventana principal
            delete(app);
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)

            %Se comprueba si existe en fichero RedLSTM
            numArchivosMat = size((dir('*.mat')));

            if numArchivosMat(1) == 0
                warndlg('No existe ningún archivo de red entrenada.','Warning')
            else
                % Se ejecuta el archivo Inicio
                InicioLSTMTFG;

                if a == 0
                     warndlg('No se ha detectado ningún móvil. Abre la aplicación de Matlab en tu móvil.','Warning')
                end 

                % Guardar la variable net en el workspace 
                assignin('base','net',net);
            end

        end

        % Button pushed function: ActividadButton
        function ActividadButtonPushed(app, event)
           
            % Número de variables del workspace
            numVariables = size(evalin('base', 'who'))
            
            if numVariables(1) == 2 % Sólo está el ID del canal y la readAPIKey
                warndlg('Primero debes activar los sensores del móvil y pulsar sobre el botón "Inicio".','Warning')
            else
                 if size(mobiledevlist) == 0
                    warndlg('No se ha detectado ningún móvil. Abre la aplicación de Matlab en tu móvil y activa los sensores.','Warning');
                 else
                     global m;
                     sensoresActivos = true

                     if m.AngularVelocitySensorEnabled == 0 |  m.AccelerationSensorEnabled == 0 | m.MagneticSensorEnabled == 0 | m.OrientationSensorEnabled == 0 | m.PositionSensorEnabled == 0
                         sensoresActivos=false
                     end

                     if sensoresActivos
                         net = evalin('base','net')

                         % Se ejecuta el archivo ActividadLSTMTFG
                         ActividadLSTMTFG

                         plot(app.UIAxes, taceleracion, yAcel)
                         texto = "Clasificación: "

                         app.clasificacion.Text = strcat(texto,lower(clasificacionEs))

                         thingspeak(X, clasificacionEs);
                     else
                         warndlg('Activa todos los sensores del móvil para poder empezar a capturar datos.','Warning');
                     end

                 end
            end

        end

        % Button pushed function: HistorialButton
        function HistorialButtonPushed(app, event)
            Historial;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, {'Tiempo relativo(s)'; ''})
            zlabel(app.UIAxes, 'Z')
            app.EntrenamientoButton = uibutton(app.GridLayout, 'push');
            app.EntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenamientoButtonPushed, true);
            app.EntrenamientoButton.Icon = fullfile(pathToMLAPP, 'icons', 'icon-mat-settings-25.png');
            app.EntrenamientoButton.Text = 'Entrenamiento';
            app.InicioButton = uibutton(app.GridLayout, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-power-off-20.png');
            app.InicioButton.Text = 'Inicio';
            app.ActividadButton = uibutton(app.GridLayout, 'push');
            app.ActividadButton.ButtonPushedFcn = createCallbackFcn(app, @ActividadButtonPushed, true);
            app.ActividadButton.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-play-circle-o-20.png');
            app.ActividadButton.Text = 'Actividad';
            app.clasificacion = uilabel(app.GridLayout);
            app.clasificacion.Text = 'Clasificación: ';
            app.HistorialButton = uibutton(app.GridLayout, 'push');
            app.HistorialButton.ButtonPushedFcn = createCallbackFcn(app, @HistorialButtonPushed, true);
            app.HistorialButton.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-calendar-20.png');
            app.HistorialButton.Text = 'Historial';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end